classdef Proj1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        EnterthepathofatextfileEditFieldLabel  matlab.ui.control.Label
        fileSel                        matlab.ui.control.EditField
        SelectButton                   matlab.ui.control.Button
        EntropyEditFieldLabel          matlab.ui.control.Label
        ExitButton                     matlab.ui.control.Button
        EntropyEditField               matlab.ui.control.EditField
        getY                           matlab.ui.control.Button
        jcEntropyTable                 matlab.ui.control.Table
        UITable                        matlab.ui.control.Table
        RelativeEntropyEditFieldLabel  matlab.ui.control.Label
        RelativeEntropyEditField       matlab.ui.control.EditField
    end


    properties (Access = private)
        originalText = ""; % This keeps hold of the file we select
        decodedText = ""; % The file we receive from the BSC_p after decoding.
        % Bits sent and received over the BSC_p
        fileSentBits = "";
        fileReceivedBits = "";

        charList = char(strcat(char(65:90),char(97:122), char(49:57), ",. "));
        % charList contains all the possible chars we have in any given
        % sample test case. we can quickly find the binary code for a
        % charachter by finding its index in the array then converting it
        % into binary.

    end

    methods (Access = private)

        function y = sendOverBSCp(app, bit)
            % Returns the reuslt "y" of a bit sent over a binary symmetric
            % channel with error probability = p.
            p = 0.05;
            random = rand(); % Returns a value between 0 & 1 by default.
            if (random > p)
                % The random value is larger than the probability of error,
                % We send back the bit unchanged.
                if(bit == '1')
                    y = true;
                else
                    y = false;
                end
                return
            else
                % Random value is less than p.
                % make the error.
                if(bit == '0')
                    y = true;
                else
                    y = false;
                end
            end


        end

        function binaryCode = getLetterCode(app, char)
            % Find the index of the given charachter in our charList,
            % then convert the index to binary.
            binaryCode = dec2bin(strfind(app.charList,char) - 1,6);
            % Minus one because MATLAB, the best programming language ever
            % made, starts its indicies from ONE, not ZERO.
        end

        function charDecoded = decodeChar(app, bitStream)
            idx = bin2dec(bitStream)+1;
            charDecoded = app.charList(idx);
        end

        function fileDecoded = decodeFile(app, bitStream)
            idx = 1;
            for i = 1:6:length(bitStream)-5
                fileDecoded(idx) = app.decodeChar(char(extractBetween(bitStream, i, i+5)));
                idx = idx + 1;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.jcEntropyTable.Data = {'-', '-'};
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            [file , path] = uigetfile ('*.txt') ;
            app.originalText = fileread (strcat(path,file));
            app.fileSel.Value = path ;

            temp =  app.fileSel.Value  ;
          
          %Entropy,Relative,PMF FN%    
           
            % Checks whether temp is a character array
            if (ischar(temp)==1)    
                l=length(temp);
                uniqueChars = unique(temp);   % String temp has all unique characters sorted
                lenChar=length(uniqueChars);
                 q = 1/lenChar ;          % relative entropy " Uniform "
            
                f=zeros(1,lenChar);
                for i=1:lenChar
                    % Count the occurence of unique characters
                    f(i)=length(findstr(temp,uniqueChars(i)));   
                end
               
                p=zeros(1,lenChar);
                PMF = [length(lenChar)]; % declare the Probability Mass Function with size of special chars
                for i=1:lenChar
                    %  Probabilities for each unique character 
                    p(i)=f(i)/lenChar;  
                    PMF(i) = p(i);  %PMF
               
                end
                
                H=0;
                R=0;
                
                for  i=1:lenChar
                     % Calculating the Entropy
                    H = H + (-1*p(i)*log2(p(i))); 
           
                     % Calculating the relative Entropy
                    R = R + (p(i)*log2(p(i)/q)); 
               end 
                
                
            end
%  Entropy FN end       %

%Print PMF%


for  i=1:lenChar
    X1 = num2cell(str2double(PMF(i)));
    X2 = num2cell(str2double(uniqueChars(i)));
    t = {PMF(i)  uniqueChars(i) };
    app.UITable.Data=[app.UITable.Data ; t];
    
end
%Print Relative Entropy%
app.RelativeEntropyEditField.Value = num2str(R);

%print entropy%
app.EntropyEditField.Value = num2str(H);
 % Prob dist plot   %
plot(app.UIAxes,PMF,"g");
           %   %
        end

        % Value changed function: fileSel
        function fileSelValueChanged(app, event)
            value = app.fileSel.Value;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % plot(app.UIAxes,pdf(temp,[0 10]));

        end

        % Value changed function: EntropyEditField
        function EntropyEditFieldValueChanged(app, event)
            value = app.EntropyEditField.Value;

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: getY
        function getYPushed(app, event)

            % Convert each char of our file into it's 6-bit code.
            app.fileSentBits = cell2mat(arrayfun(@app.getLetterCode,app.originalText, 'UniformOutput', false));

            % Send the file on the binary symmetric channel

            zeroASCII = 48;
            bitStreamLogicalArray = arrayfun(@app.sendOverBSCp, app.fileSentBits);
            app.fileReceivedBits = char(double(bitStreamLogicalArray)+zeroASCII);


            % Calculate the joint entropy:

            % We put the sent and received bits each in a colomn in a
            % matrix, then we calculate the probability of each combination
            % of zeros and ones. Ex:
            %  -----------------------------------------------------------
            % |                         |                                 |
            % |         SENT BITS       |          RECEIVED BITS          |
            % |                         |                                 |
            % |-----------------------------------------------------------|
            % |             0           |                 0               |
            % |-----------------------------------------------------------|
            % |             0           |                 1               |
            % |-----------------------------------------------------------|
            % |             1           |                 0               |
            % |-----------------------------------------------------------|
            % |             1           |                 1               |
            % |-----------------------------------------------------------|

            % We then calculate the probabilty of occurence for each unique
            % pair of bits, then we calculate the joint entropy.


            bitMatrix = [transpose(app.fileSentBits) transpose(app.fileReceivedBits)];
            % This makes the table shown above. We now calculate the
            % probability of occurrence for each unqiue pair of bits,
            % namely (00, 01, 10, 11)

            % Probabilities of each unique pair
            pairCount = zeros(4);
            pairCount = pairCount(1,:);

            pairProb = zeros(4);
            pairProb = pairProb(1,:);

            % each index corresponds to a pair. For Ex: pairProb(4) will
            % give us the probability of the pair "11"

            pairCount(1) = sum((bitMatrix(:,1) == '0') & (bitMatrix(:,2) == '0'));
            pairCount(2) = sum((bitMatrix(:,1) == '0') & (bitMatrix(:,2) == '1'));
            pairCount(3) = sum((bitMatrix(:,1) == '1') & (bitMatrix(:,2) == '0'));
            pairCount(4) = sum((bitMatrix(:,1) == '1') & (bitMatrix(:,2) == '1'));

            totalChars = length(app.fileSentBits);
            pairProb = pairCount / totalChars
            
            % Now, all that remains for us is to calculate the joint
            % entropy using the rule : -summation p(x,y) * log_2(p(x,y))
            
            jointEntropyXY = -1 .* sum(pairProb .* log2(pairProb));
            app.jcEntropyTable.Data = {jointEntropyXY, '01010101'};
            % Calculate the conditional entropy
            % pass

            % Proof
            % Pass


        end

        % Value changed function: RelativeEntropyEditField
        function RelativeEntropyEditFieldValueChanged(app, event)
            value = app.RelativeEntropyEditField.Value;
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Probability Distribution')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.EnterthepathofatextfileEditFieldLabel = uilabel(app.UIFigure);
            app.EnterthepathofatextfileEditFieldLabel.Text = 'Enter the path of a text file';
            app.fileSel = uieditfield(app.UIFigure, 'text');
            app.fileSel.ValueChangedFcn = createCallbackFcn(app, @fileSelValueChanged, true);
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.EntropyEditFieldLabel = uilabel(app.UIFigure);
            app.EntropyEditFieldLabel.Text = 'Entropy';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.EntropyEditField = uieditfield(app.UIFigure, 'text');
            app.EntropyEditField.ValueChangedFcn = createCallbackFcn(app, @EntropyEditFieldValueChanged, true);
            app.EntropyEditField.Editable = 'off';
            app.getY = uibutton(app.UIFigure, 'push');
            app.getY.ButtonPushedFcn = createCallbackFcn(app, @getYPushed, true);
            app.getY.Text = 'Generate Faulty Output';
            app.jcEntropyTable = uitable(app.UIFigure);
            app.jcEntropyTable.ColumnName = {'Joint Entropy'; 'Conditional Entropy'};
            app.jcEntropyTable.RowName = {};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'PMF'; 'Chars'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = false;
            app.UITable.ColumnEditable = false;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.RelativeEntropyEditFieldLabel = uilabel(app.UIFigure);
            app.RelativeEntropyEditFieldLabel.Text = 'Relative Entropy';
            app.RelativeEntropyEditField = uieditfield(app.UIFigure, 'text');
            app.RelativeEntropyEditField.ValueChangedFcn = createCallbackFcn(app, @RelativeEntropyEditFieldValueChanged, true);
            app.RelativeEntropyEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Proj1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end